classdef LeftRightCenterGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        P1Win                      matlab.ui.control.Image
        P2Win                      matlab.ui.control.Image
        P3Win                      matlab.ui.control.Image
        ContinueGameButton         matlab.ui.control.Button
        StartGameButton            matlab.ui.control.Button
        StartPage                  matlab.ui.control.Image
        EndTurn                    matlab.ui.control.Button
        RollDiceButtonPlayer2Roll  matlab.ui.control.Button
        P2Score                    matlab.ui.control.Table
        P1Score                    matlab.ui.control.Table
        P3Score                    matlab.ui.control.Table
        CScore                     matlab.ui.control.Table
        RulesButton                matlab.ui.control.Button
        LeftRightCenterGameLabel   matlab.ui.control.Label
        CIcon                      matlab.ui.control.Image
        MainTable                  matlab.ui.control.Table
        P3Icon                     matlab.ui.control.Image
        P1Icon                     matlab.ui.control.Image
        P2Icon                     matlab.ui.control.Image
        PlayerOneRollButton        matlab.ui.control.Button
        Dice1Roll1                 matlab.ui.control.Image
        Dice1Roll2                 matlab.ui.control.Image
        Dice1Roll3                 matlab.ui.control.Image
        Dice1Roll4                 matlab.ui.control.Image
        Dice1Roll5                 matlab.ui.control.Image
        Dice1Roll6                 matlab.ui.control.Image
        Dice2Roll1                 matlab.ui.control.Image
        Dice2Roll2                 matlab.ui.control.Image
        Dice2Roll3                 matlab.ui.control.Image
        Dice2Roll4                 matlab.ui.control.Image
        Dice2Roll5                 matlab.ui.control.Image
        Dice2Roll6                 matlab.ui.control.Image
        Dice3Roll1                 matlab.ui.control.Image
        Dice3Roll2                 matlab.ui.control.Image
        Dice3Roll3                 matlab.ui.control.Image
        Dice3Roll4                 matlab.ui.control.Image
        Dice3Roll5                 matlab.ui.control.Image
        Dice3Roll6                 matlab.ui.control.Image
        PlayerThreeRollButton      matlab.ui.control.Button
        Background                 matlab.ui.control.Image
    end


  %Ensures variable updates
  properties (Access = public)
        player1Chips;
        player2Chips;
        player3Chips;
        centerChips;
        turnNum;
        rollOutput;
        p1HasChips;
        p2HasChips;
        p3HasChips;
        hasChips;
        player1Rolls;
        player2Rolls;
        player3Rolls;
        diceSetP1;
        diceSetP2;
        diceSetP3;
    end
    
    properties (Access = private)
        ChannelID= 1967663
        writeAPIkey='X1J21ZSFNNPKQW3V'
        readAPIkey='9JQKDXAAUVVH68YK'
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
   
            %Initial distribution
            app.player1Chips = 3; 
            app.player2Chips = 3;
            app.player3Chips = 3;
            app.centerChips = 0;
            app.turnNum = 0;
            app.P1Score.Data = app.player1Chips;
            app.P2Score.Data = app.player2Chips;
            app.P3Score.Data = app.player3Chips;
            app.CScore.Data = app.centerChips;
            app.ContinueGameButton.Visible = "off";
            
            %hides images of player winning
            app.P1Win.Visible ="off";
            app.P2Win.Visible ="off";
            app.P3Win.Visible ="off";
            
            %hides images of dice
            app.Dice1Roll1.Visible ="off";
            app.Dice1Roll2.Visible ="off";
            app.Dice1Roll3.Visible ="off";
            app.Dice1Roll4.Visible ="off";
            app.Dice1Roll5.Visible ="off";
            app.Dice1Roll6.Visible ="off";
            
            app.Dice2Roll1.Visible ="off";
            app.Dice2Roll2.Visible ="off";
            app.Dice2Roll3.Visible ="off";
            app.Dice2Roll4.Visible ="off";
            app.Dice2Roll5.Visible ="off";
            app.Dice2Roll6.Visible ="off";
            
            app.Dice3Roll1.Visible ="off";
            app.Dice3Roll2.Visible ="off";
            app.Dice3Roll3.Visible ="off";
            app.Dice3Roll4.Visible ="off";
            app.Dice3Roll5.Visible ="off";
            app.Dice3Roll6.Visible ="off";
    
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.StartPage.Visible = "off";
            app.StartGameButton.Visible = "off";
           
            %If an error appears here saying its not in the directory and
            %cant find sound then go to the command window right click the
            %sound folder and add folder and subfolders to path. This will
            %then fix all sound errors

            dir("Sounds/")
            [a, Fs]= audioread('Start.wav')
            startSound = audioplayer(a(:,1),Fs)
            play(startSound)

        end

        % Button pushed function: PlayerOneRollButton
        function PlayerOneRollButtonPushed(app, event)
          
            %Dice Sound
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Dice.mp3')     
            diceSound = audioplayer(a, Fs)
            play(diceSound)

            %Checks for a winner yet
            app.p1HasChips = app.player1Chips > 0;
            app.p2HasChips = app.player2Chips > 0;
            app.p3HasChips = app.player3Chips > 0;
            app.hasChips = app.p1HasChips + app.p2HasChips + app.p3HasChips;

            %Player One Turn


            %Determines number of player rolls
            if app.player1Chips > 3
            app.player1Rolls = 3;
            else
            app.player1Rolls = app.player1Chips;
            end
            app.diceSetP1 = randi([1 6], 1, app.player1Rolls);

            %Displays the dice image of what the player rolled 
            if length(app.diceSetP1) >= 1
                roll1 = app.diceSetP1(1);
                switch roll1
                    case 1
                    app.Dice1Roll1.Visible ="on";
                    case 2
                    app.Dice1Roll2.Visible ="on";
                    case 3
                    app.Dice1Roll3.Visible ="on";
                    case 4
                    app.Dice1Roll4.Visible ="on";
                    case 5
                    app.Dice1Roll5.Visible ="on";
                    case 6
                    app.Dice1Roll6.Visible ="on";
                    otherwise
                    app.Dice1Roll1.Visible ="off";
                    app.Dice1Roll2.Visible ="off";
                    app.Dice1Roll3.Visible ="off";
                    app.Dice1Roll4.Visible ="off";
                    app.Dice1Roll5.Visible ="off";
                    app.Dice1Roll6.Visible ="off";
                end
            end

            if length(app.diceSetP1) >= 2
                roll2 = app.diceSetP1(2);
                switch roll2
                    case 1
                    app.Dice2Roll1.Visible ="on";
                    case 2
                    app.Dice2Roll2.Visible ="on";
                    case 3
                    app.Dice2Roll3.Visible ="on";
                    case 4
                    app.Dice2Roll4.Visible ="on";
                    case 5
                    app.Dice2Roll5.Visible ="on";
                    case 6
                    app.Dice2Roll6.Visible ="on";
                    otherwise
                    app.Dice2Roll1.Visible ="off";
                    app.Dice2Roll2.Visible ="off";
                    app.Dice2Roll3.Visible ="off";
                    app.Dice2Roll4.Visible ="off";
                    app.Dice2Roll5.Visible ="off";
                    app.Dice2Roll6.Visible ="off";
                end
            end
            
            if length(app.diceSetP1) == 3
                roll3 = app.diceSetP1(3);
                switch roll3
                    case 1
                    app.Dice3Roll1.Visible ="on";
                    case 2
                    app.Dice3Roll2.Visible ="on";
                    case 3
                    app.Dice3Roll3.Visible ="on";
                    case 4
                    app.Dice3Roll4.Visible ="on";
                    case 5
                    app.Dice3Roll5.Visible ="on";
                    case 6
                    app.Dice3Roll6.Visible ="on";
                    otherwise
                    app.Dice3Roll1.Visible ="off";
                    app.Dice3Roll2.Visible ="off";
                    app.Dice3Roll3.Visible ="off";
                    app.Dice3Roll4.Visible ="off";
                    app.Dice3Roll5.Visible ="off";
                    app.Dice3Roll6.Visible ="off";
                end
            end

            %Rolls if there is no winner yet
            if app.hasChips > 1
                for dice = app.diceSetP1(1:end)
                    if dice==1 || dice==2 || dice==3
                    app.player1Chips = app.player1Chips;
                    end
                    if dice==4
                    app.player1Chips = app.player1Chips - 1;
                    app.player3Chips = app.player3Chips + 1;
                    end
                    if dice==5
                    app.player1Chips = app.player1Chips - 1;
                    app.centerChips = app.centerChips + 1;
                    end
                    if dice==6
                    app.player1Chips = app.player1Chips - 1;
                    app.player2Chips = app.player2Chips + 1;
                    end
                end
              

                displayString = "";
                for item = app.diceSetP1(1:end)
                    displayString = append(displayString, "[", string(item), "]");
                end
                
                app.rollOutput = [app.player1Chips,app.player2Chips,app.player3Chips,app.centerChips];
                app.turnNum = app.turnNum + 1;
                app.MainTable.Data = [app.MainTable.Data; (app.turnNum),displayString];
                app.P1Score.Data = app.rollOutput(1);
                app.P2Score.Data = app.rollOutput(2);
                app.P3Score.Data = app.rollOutput(3);
                app.CScore.Data = app.rollOutput(4);
              
            end
        
       %Checks if there is a winner and displays winner banner  
       player12Total = app.player1Chips + app.player2Chips;
       player23Total = app.player3Chips + app.player2Chips;
       player13Total = app.player1Chips + app.player3Chips;
        
        if player12Total <= 0
            app.P3Win.Visible = "on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player23Total <= 0
            app.P1Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player13Total <= 0
            app.P2Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        end 
         

        end

        % Button pushed function: RollDiceButtonPlayer2Roll
        function RollDiceButtonPlayer2RollPushed(app, event)

            %Dice Sound
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Dice.mp3')     
            diceSound = audioplayer(a, Fs)
            play(diceSound)
           
            %Player 2 Turn

            %Checks for a winner yet
            app.p1HasChips = app.player1Chips > 0;
            app.p2HasChips = app.player2Chips > 0;
            app.p3HasChips = app.player3Chips > 0;
            app.hasChips = app.p1HasChips + app.p2HasChips + app.p3HasChips;

            %Determines number of player rolls
            if app.player2Chips > 3
            app.player2Rolls = 3;
            else
            app.player2Rolls = app.player2Chips;
            end
            app.diceSetP2 = randi([1 6], 1, app.player2Rolls);

                        %Displays the dice image of what the player rolled 
            if length(app.diceSetP2) >= 1
                roll1 = app.diceSetP2(1);
                switch roll1
                    case 1
                    app.Dice1Roll1.Visible ="on";
                    case 2
                    app.Dice1Roll2.Visible ="on";
                    case 3
                    app.Dice1Roll3.Visible ="on";
                    case 4
                    app.Dice1Roll4.Visible ="on";
                    case 5
                    app.Dice1Roll5.Visible ="on";
                    case 6
                    app.Dice1Roll6.Visible ="on";
                    otherwise
                    app.Dice1Roll1.Visible ="off";
                    app.Dice1Roll2.Visible ="off";
                    app.Dice1Roll3.Visible ="off";
                    app.Dice1Roll4.Visible ="off";
                    app.Dice1Roll5.Visible ="off";
                    app.Dice1Roll6.Visible ="off";
                end
            end

            if length(app.diceSetP2) >= 2
                roll2 = app.diceSetP2(2);
                switch roll2
                    case 1
                    app.Dice2Roll1.Visible ="on";
                    case 2
                    app.Dice2Roll2.Visible ="on";
                    case 3
                    app.Dice2Roll3.Visible ="on";
                    case 4
                    app.Dice2Roll4.Visible ="on";
                    case 5
                    app.Dice2Roll5.Visible ="on";
                    case 6
                    app.Dice2Roll6.Visible ="on";
                    otherwise
                    app.Dice2Roll1.Visible ="off";
                    app.Dice2Roll2.Visible ="off";
                    app.Dice2Roll3.Visible ="off";
                    app.Dice2Roll4.Visible ="off";
                    app.Dice2Roll5.Visible ="off";
                    app.Dice2Roll6.Visible ="off";
                end
            end
            
            if length(app.diceSetP2) == 3
                roll3 = app.diceSetP2(3);
                switch roll3
                    case 1
                    app.Dice3Roll1.Visible ="on";
                    case 2
                    app.Dice3Roll2.Visible ="on";
                    case 3
                    app.Dice3Roll3.Visible ="on";
                    case 4
                    app.Dice3Roll4.Visible ="on";
                    case 5
                    app.Dice3Roll5.Visible ="on";
                    case 6
                    app.Dice3Roll6.Visible ="on";
                    otherwise
                    app.Dice3Roll1.Visible ="off";
                    app.Dice3Roll2.Visible ="off";
                    app.Dice3Roll3.Visible ="off";
                    app.Dice3Roll4.Visible ="off";
                    app.Dice3Roll5.Visible ="off";
                    app.Dice3Roll6.Visible ="off";
                end
            end

            %Rolls if there is no winner yet
            if app.hasChips > 1
                for dice = app.diceSetP2(1:end)
                    if dice==1 || dice==2 || dice==3
                    app.player2Chips = app.player2Chips;
                    end
                    if dice==4
                    app.player2Chips = app.player2Chips - 1;
                    app.player1Chips = app.player1Chips + 1;
                    end
                    if dice==5
                    app.player2Chips = app.player2Chips - 1;
                    app.centerChips = app.centerChips + 1;
                    end
                    if dice==6
                    app.player2Chips = app.player2Chips - 1;
                    app.player3Chips = app.player3Chips + 1;
                    end
            
                end

                displayString = "";
                for item = app.diceSetP2(1:end)
                    displayString = append(displayString, "[", string(item), "]");
                end
                
                app.rollOutput = [app.player1Chips,app.player2Chips,app.player3Chips,app.centerChips];
                app.turnNum = app.turnNum + 1;
                app.MainTable.Data = [app.MainTable.Data; (app.turnNum),displayString];
                app.P1Score.Data = app.rollOutput(1);
                app.P2Score.Data = app.rollOutput(2);
                app.P3Score.Data = app.rollOutput(3);
                app.CScore.Data = app.rollOutput(4);
            end

       %Checks if there is a winner and displays winner banner  
       player12Total = app.player1Chips + app.player2Chips;
       player23Total = app.player3Chips + app.player2Chips;
       player13Total = app.player1Chips + app.player3Chips;
        
        if player12Total <= 0
            app.P3Win.Visible = "on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player23Total <= 0
            app.P1Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player13Total <= 0
            app.P2Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        end 


        end

        % Button pushed function: PlayerThreeRollButton
        function PlayerThreeRollButtonPushed(app, event)
      
            %Dice Sound
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Dice.mp3')     
            diceSound = audioplayer(a, Fs)
            play(diceSound)
            
            %Player 3 Turn

            %Checks for a winner yet
            app.p1HasChips = app.player1Chips > 0;
            app.p2HasChips = app.player2Chips > 0;
            app.p3HasChips = app.player3Chips > 0;
            app.hasChips = app.p1HasChips + app.p2HasChips + app.p3HasChips;

            %Determines number of player rolls
            if app.player3Chips > 3
            app.player3Rolls = 3;
            else
            app.player3Rolls = app.player3Chips;
            end
            app.diceSetP3 = randi([1 6], 1, app.player3Rolls);
            
            %Displays the dice image of what the player rolled 
            if length(app.diceSetP3) >= 1
                roll1 = app.diceSetP3(1);
                switch roll1
                    case 1
                    app.Dice1Roll1.Visible ="on";
                    case 2
                    app.Dice1Roll2.Visible ="on";
                    case 3
                    app.Dice1Roll3.Visible ="on";
                    case 4
                    app.Dice1Roll4.Visible ="on";
                    case 5
                    app.Dice1Roll5.Visible ="on";
                    case 6
                    app.Dice1Roll6.Visible ="on";
                    otherwise
                    app.Dice1Roll1.Visible ="off";
                    app.Dice1Roll2.Visible ="off";
                    app.Dice1Roll3.Visible ="off";
                    app.Dice1Roll4.Visible ="off";
                    app.Dice1Roll5.Visible ="off";
                    app.Dice1Roll6.Visible ="off";
                end
            end

            if length(app.diceSetP3) >= 2
                roll2 = app.diceSetP3(2);
                switch roll2
                    case 1
                    app.Dice2Roll1.Visible ="on";
                    case 2
                    app.Dice2Roll2.Visible ="on";
                    case 3
                    app.Dice2Roll3.Visible ="on";
                    case 4
                    app.Dice2Roll4.Visible ="on";
                    case 5
                    app.Dice2Roll5.Visible ="on";
                    case 6
                    app.Dice2Roll6.Visible ="on";
                    otherwise
                    app.Dice2Roll1.Visible ="off";
                    app.Dice2Roll2.Visible ="off";
                    app.Dice2Roll3.Visible ="off";
                    app.Dice2Roll4.Visible ="off";
                    app.Dice2Roll5.Visible ="off";
                    app.Dice2Roll6.Visible ="off";
                end
            end
            
            if length(app.diceSetP3) == 3
                roll3 = app.diceSetP3(3);
                switch roll3
                    case 1
                    app.Dice3Roll1.Visible ="on";
                    case 2
                    app.Dice3Roll2.Visible ="on";
                    case 3
                    app.Dice3Roll3.Visible ="on";
                    case 4
                    app.Dice3Roll4.Visible ="on";
                    case 5
                    app.Dice3Roll5.Visible ="on";
                    case 6
                    app.Dice3Roll6.Visible ="on";
                    otherwise
                    app.Dice3Roll1.Visible ="off";
                    app.Dice3Roll2.Visible ="off";
                    app.Dice3Roll3.Visible ="off";
                    app.Dice3Roll4.Visible ="off";
                    app.Dice3Roll5.Visible ="off";
                    app.Dice3Roll6.Visible ="off";
                end
            end

            %Rolls if there is no winner yet
            if app.hasChips > 1
                for dice = app.diceSetP3(1:end)
                    if dice==1 || dice==2 || dice==3
                    app.player3Chips = app.player3Chips;
                    end
                    if dice==4
                    app.player3Chips = app.player3Chips - 1;
                    app.player2Chips = app.player2Chips + 1;
                    end
                    if dice==5
                    app.player3Chips = app.player3Chips - 1;
                    app.centerChips = app.centerChips + 1;
                    end
                    if dice==6
                    app.player3Chips = app.player3Chips - 1;
                    app.player1Chips = app.player1Chips + 1;
                    end
                end

                displayString = "";
                for item = app.diceSetP3(1:end)
                    displayString = append(displayString, "[", string(item), "]");
                end
                
                app.rollOutput = [app.player1Chips,app.player2Chips,app.player3Chips,app.centerChips];
                app.turnNum = app.turnNum + 1;
                app.MainTable.Data = [app.MainTable.Data; (app.turnNum),displayString];
                app.P1Score.Data = app.rollOutput(1);
                app.P2Score.Data = app.rollOutput(2);
                app.P3Score.Data = app.rollOutput(3);
                app.CScore.Data = app.rollOutput(4);
            end

       %Checks if there is a winner and displays winner banner  
       player12Total = app.player1Chips + app.player2Chips;
       player23Total = app.player3Chips + app.player2Chips;
       player13Total = app.player1Chips + app.player3Chips;
        
        if player12Total <= 0
            app.P3Win.Visible = "on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player23Total <= 0
            app.P1Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        elseif player13Total <= 0
            app.P2Win.Visible ="on";
            stop(diceSound)
            dir("Sounds/")
            [a, Fs]= audioread('Sounds/Winner.mp3')     
            winner = audioplayer(a, Fs)
            play(winner)
        
        end 

        end

        % Button pushed function: EndTurn
        function EndTurnButtonPushed(app, event)
            %hides images of dice
            app.Dice1Roll1.Visible ="off";
            app.Dice1Roll2.Visible ="off";
            app.Dice1Roll3.Visible ="off";
            app.Dice1Roll4.Visible ="off";
            app.Dice1Roll5.Visible ="off";
            app.Dice1Roll6.Visible ="off";
            app.Dice2Roll1.Visible ="off";
            app.Dice2Roll2.Visible ="off";
            app.Dice2Roll3.Visible ="off";
            app.Dice2Roll4.Visible ="off";
            app.Dice2Roll5.Visible ="off";
            app.Dice2Roll6.Visible ="off";
            app.Dice3Roll1.Visible ="off";
            app.Dice3Roll2.Visible ="off";
            app.Dice3Roll3.Visible ="off";
            app.Dice3Roll4.Visible ="off";
            app.Dice3Roll5.Visible ="off";
            app.Dice3Roll6.Visible ="off";
        end

        % Cell edit callback: MainTable
        function MainTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;                       
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            app.StartPage.Visible = "on";
            app.StartGameButton.Visible = "off";
            app.ContinueGameButton.Visible = "on";
        end

        % Button pushed function: ContinueGameButton
        function ContinueGameButtonPushed(app, event)
            app.StartPage.Visible = "off";
            app.ContinueGameButton.Visible = "off";
        end

        % Callback function
        function ThingSpeakButtonPushed(app, event)
            app.player1Chips=thingSpeakRead(app.ChannelID, 'Fields',1, 'ReadKey',app.readAPIkey);
            app.player2Chips=thingSpeakRead(app.ChannelID, 'Fields',2, 'ReadKey',app.readAPIkey);
            app.player3Chips=thingSpeakRead(app.ChannelID, 'Fields',3, 'ReadKey',app.readAPIkey);
            app.centerChips=thingSpeakRead(app.ChannelID, 'Fields',4, 'ReadKey',app.readAPIkey);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Images', 'Background.jpg');
            app.PlayerThreeRollButton = uibutton(app.UIFigure, 'push');
            app.PlayerThreeRollButton.ButtonPushedFcn = createCallbackFcn(app, @PlayerThreeRollButtonPushed, true);
            app.PlayerThreeRollButton.Text = 'Player Three Roll';
            app.Dice3Roll6 = uiimage(app.UIFigure);
            app.Dice3Roll6.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side6.jpg');
            app.Dice3Roll5 = uiimage(app.UIFigure);
            app.Dice3Roll5.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side5.jpg');
            app.Dice3Roll4 = uiimage(app.UIFigure);
            app.Dice3Roll4.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side4.jpg');
            app.Dice3Roll3 = uiimage(app.UIFigure);
            app.Dice3Roll3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side3.jpg');
            app.Dice3Roll2 = uiimage(app.UIFigure);
            app.Dice3Roll2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side2.jpg');
            app.Dice3Roll1 = uiimage(app.UIFigure);
            app.Dice3Roll1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side1.jpg');
            app.Dice2Roll6 = uiimage(app.UIFigure);
            app.Dice2Roll6.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side6.jpg');
            app.Dice2Roll5 = uiimage(app.UIFigure);
            app.Dice2Roll5.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side5.jpg');
            app.Dice2Roll4 = uiimage(app.UIFigure);
            app.Dice2Roll4.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side4.jpg');
            app.Dice2Roll3 = uiimage(app.UIFigure);
            app.Dice2Roll3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side3.jpg');
            app.Dice2Roll2 = uiimage(app.UIFigure);
            app.Dice2Roll2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side2.jpg');
            app.Dice2Roll1 = uiimage(app.UIFigure);
            app.Dice2Roll1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side1.jpg');
            app.Dice1Roll6 = uiimage(app.UIFigure);
            app.Dice1Roll6.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side6.jpg');
            app.Dice1Roll5 = uiimage(app.UIFigure);
            app.Dice1Roll5.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side5.jpg');
            app.Dice1Roll4 = uiimage(app.UIFigure);
            app.Dice1Roll4.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side4.jpg');
            app.Dice1Roll3 = uiimage(app.UIFigure);
            app.Dice1Roll3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side3.jpg');
            app.Dice1Roll2 = uiimage(app.UIFigure);
            app.Dice1Roll2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side2.jpg');
            app.Dice1Roll1 = uiimage(app.UIFigure);
            app.Dice1Roll1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Side1.jpg');
            app.PlayerOneRollButton = uibutton(app.UIFigure, 'push');
            app.PlayerOneRollButton.ButtonPushedFcn = createCallbackFcn(app, @PlayerOneRollButtonPushed, true);
            app.PlayerOneRollButton.Text = 'Player One Roll';
            app.P2Icon = uiimage(app.UIFigure);
            app.P2Icon.ImageSource = fullfile(pathToMLAPP, 'Images', 'Controller.png');
            app.P1Icon = uiimage(app.UIFigure);
            app.P1Icon.ImageSource = fullfile(pathToMLAPP, 'Images', 'Controller.png');
            app.P3Icon = uiimage(app.UIFigure);
            app.P3Icon.ImageSource = fullfile(pathToMLAPP, 'Images', 'Controller.png');
            app.MainTable = uitable(app.UIFigure);
            app.MainTable.ColumnName = {'Turn'; 'Roll'};
            app.MainTable.RowName = {};
            app.MainTable.CellEditCallback = createCallbackFcn(app, @MainTableCellEdit, true);
            app.CIcon = uiimage(app.UIFigure);
            app.CIcon.ImageSource = fullfile(pathToMLAPP, 'Images', 'Coin.png');
            app.LeftRightCenterGameLabel = uilabel(app.UIFigure);
            app.LeftRightCenterGameLabel.Text = 'Left Right Center Game';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.CScore = uitable(app.UIFigure);
            app.CScore.ColumnName = {'Center'};
            app.CScore.RowName = {};
            app.P3Score = uitable(app.UIFigure);
            app.P3Score.ColumnName = {'Player 3'};
            app.P3Score.RowName = {};
            app.P1Score = uitable(app.UIFigure);
            app.P1Score.ColumnName = {'Player 1'};
            app.P1Score.RowName = {};
            app.P2Score = uitable(app.UIFigure);
            app.P2Score.ColumnName = {'Player 2'};
            app.P2Score.RowName = {};
            app.RollDiceButtonPlayer2Roll = uibutton(app.UIFigure, 'push');
            app.RollDiceButtonPlayer2Roll.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPlayer2RollPushed, true);
            app.RollDiceButtonPlayer2Roll.Text = 'Player Two Roll';
            app.EndTurn = uibutton(app.UIFigure, 'push');
            app.EndTurn.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurn.Text = 'End Turn';
            app.StartPage = uiimage(app.UIFigure);
            app.StartPage.ImageSource = fullfile(pathToMLAPP, 'Images', 'StartPage.png');
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.ContinueGameButton = uibutton(app.UIFigure, 'push');
            app.ContinueGameButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueGameButtonPushed, true);
            app.ContinueGameButton.Text = 'Continue Game';
            app.P3Win = uiimage(app.UIFigure);
            app.P3Win.ImageSource = fullfile(pathToMLAPP, 'Images', 'LRC_P3_Wins.png');
            app.P2Win = uiimage(app.UIFigure);
            app.P2Win.ImageSource = fullfile(pathToMLAPP, 'Images', 'LRC_P2_Wins.png');
            app.P1Win = uiimage(app.UIFigure);
            app.P1Win.ImageSource = fullfile(pathToMLAPP, 'Images', 'LRC_P1_Wins.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeftRightCenterGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end